classdef CV_challenge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        detectcameraParButton         matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ReadyLamp_3                   matlab.ui.control.Lamp
        ReadyLamp_3Label              matlab.ui.control.Label
        ReadyLamp_2                   matlab.ui.control.Lamp
        ReadyLamp_2Label              matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        ReadyLampLabel                matlab.ui.control.Label
        imageSizeEditField_2          matlab.ui.control.NumericEditField
        principalPointEditField_2     matlab.ui.control.NumericEditField
        focalLengthEditField_2        matlab.ui.control.NumericEditField
        imageSizeEditField            matlab.ui.control.NumericEditField
        imageSizeEditFieldLabel       matlab.ui.control.Label
        principalPointEditField       matlab.ui.control.NumericEditField
        principalPointEditFieldLabel  matlab.ui.control.Label
        focalLengthEditField          matlab.ui.control.NumericEditField
        focalLengthEditFieldLabel     matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        SelectImagesButton            matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        images
        intrinsics
        imds
        SelectImagesButtonPressed = false;
        ConfirmButtonPressed = false;
        detectcameraParButtonPressed = false;
        opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
        opts1 = struct('Default','Cancel',... 
              'Interpreter','tex');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            [folderPath] = uigetdir('','Select a folder');
            if isequal(folderPath,0)
                errordlg("\fontsize{16}No images selected","error",app.opts);
                return;
            else
                imageDir = fullfile(folderPath);
                app.imds = imageDatastore(imageDir);
            end
            app.ReadyLamp_2.Color = "0.00,1.00,0.00";
            app.SelectImagesButtonPressed = true;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Init
            if ~app.SelectImagesButtonPressed
                errordlg("\fontsize{16}Please select images first.","error",app.opts);
                return;
            elseif ~app.ConfirmButtonPressed
                errordlg("\fontsize{16}Please comfirm the parameters of the camera.","error",app.opts);
                return;
            end
            app.images = cell(1, numel(app.imds.Files));
            for i = 1:numel(app.imds.Files)
                I = readimage(app.imds, i);
                app.images{i} = im2gray(I);
            end
            prevPoints   = detectSIFTFeatures(app.images{1});
            [prevFeatures, prevPoints]= extractFeatures(app.images{1}, prevPoints);
            %% 建立一个vSet。
            vSet = imageviewset;
            viewId = 1;
            vSet = addView(vSet, viewId, rigidtform3d, Points=prevPoints);
            %% 图像循环对比
            test =4;
            for i =  2:test
                %I = undistortImage(images{i}, intrinsics);
                %找到第i张图的corner
                currPoints = detectSIFTFeatures(app.images{i});
                %extract筛选这些特征点。features 用于比较不同图像之间的特征。valid_points2提供关键点的位置信息（行列NX2）。
                [currFeatures,currPoints] = extractFeatures(app.images{i}, currPoints);
                %匹配特征（用于匹配两组特征点之间的最佳匹配）。
                indexPairs = matchFeatures(prevFeatures,currFeatures);
                %每一行表示一对匹配特征的索引，第一列是 features1 中的索引，第二列是 features2 中的索引。
                matchedPoints1 = prevPoints(indexPairs(:,1),:);
                matchedPoints2 = currPoints(indexPairs(:,2),:);
                % 通过两张图的特征点的位置求出相对坐标。
                [relPose, inlierIdx] = helperEstimateRelativePose(...
                    matchedPoints1, matchedPoints2, app.intrinsics);
                % 把前一张图的绝对位置（相机相对于世界）存在prepose。i=2的时候就是第一张图。里面包含RTA。
                prevPose = poses(vSet, i-1).AbsolutePose;
                % 前一张图的位置xA得到现在图的位置。
                currPose = rigidtform3d(prevPose.A*relPose.A);
                % 把下面属性存进vSet_View中。
                vSet = addView(vSet, i, currPose, Points=currPoints);
                % 把下面属性存进vSet_Connection中
                vSet = addConnection(vSet, i-1, i, relPose, Matches=indexPairs(inlierIdx,:));
                % 根据views找到点再图中坐标。
                tracks = findTracks(vSet);
                % 根据view确认每张图camera在世界的位置。
                camPoses = poses(vSet);
                % 点的3D坐标和优化。可能同一个点的世界坐标有些偏差。
                xyzPoints = triangulateMultiview(tracks, camPoses, app.intrinsics);
                [xyzPoints, camPoses, reprojectionErrors] = bundleAdjustment(xyzPoints, ...
                    tracks, camPoses, app.intrinsics, FixedViewId=1, ...
                    PointsUndistorted=true);
                % 优化相机位置。
                vSet = updateView(vSet, camPoses);
                % 把当前图像替换给pre，为下次循环做准备。
                prevFeatures = currFeatures;
                prevPoints   = currPoints;
            end
            %% 画图有问题
            % Display camera poses.
            axesHandle = app.UIAxes;
            %% camera
            camPoses = poses(vSet);
            PreCenter = [0, 0, 0];  % 相机中心坐标
            PreOrientation = eye(3);  % 相机朝向矩阵
            text(axesHandle,PreCenter(1),PreCenter(2),PreCenter(3),num2str(1));
            hold(axesHandle, 'on');
            plot3(axesHandle, PreCenter(1), PreCenter(2), PreCenter(3), 'ro', 'MarkerSize', 10);
            hold(axesHandle, 'on');
            for i =  2:test
                CurCamPos = PreCenter*camPoses.AbsolutePose(i,1).R+camPoses.AbsolutePose(i,1).Translation;
                hold(axesHandle, 'on');
                text(axesHandle,CurCamPos(1),CurCamPos(2),CurCamPos(3),num2str(i));
                hold(axesHandle, 'on');
                plot3(axesHandle, CurCamPos(1), CurCamPos(2), CurCamPos(3),'square','color','r', 'MarkerSize', 10);
                PreCenter = CurCamPos;
            end
            % figure;
            % picCamera = plotCamera(camPoses, 'size',0.2);
            % frame = getframe(axesHandle);
            % imshow(picCamera, 'Parent', axesHandle);
            % hold on
            %% 3D
            % Exclude noisy 3-D points.
            goodIdx = (reprojectionErrors < 5);
            xyzPoints = xyzPoints(goodIdx, :);
            % Display the 3-D points.          
            % picPC = pcshow(xyzPoints, VerticalAxis='y', VerticalAxisDir='down', MarkerSize= 45);
            xyzPoints1 = xyzPoints';
            scatter3(axesHandle,xyzPoints1(1,:),xyzPoints1(2,:),xyzPoints1(3,:));
            grid(axesHandle,"on") 
            hold(axesHandle,"off") 
            %imshow(picPC, 'Parent', axesHandle);
            
            % Specify the viewing volume.
            loc1 = camPoses.AbsolutePose(1).Translation;
            xlim(axesHandle,[loc1(1)-5, loc1(1)+4]);
            ylim(axesHandle,[loc1(2)-5, loc1(2)+4]);
            zlim(axesHandle,[loc1(3)-1, loc1(3)+20]);
            camorbit(axesHandle,0, -30);
            app.ReadyLamp_3.Color = "0.00,1.00,0.00";

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if ~app.detectcameraParButtonPressed
                errordlg("\fontsize{16}Please detect the parameters of the camera first.","error",app.opts);
                return;
            end
            f1=app.focalLengthEditField.Value;
            f2=app.focalLengthEditField_2.Value;
            p1=app.principalPointEditField.Value;
            p2=app.principalPointEditField_2.Value;
            im1=app.imageSizeEditField.Value;
            im2=app.imageSizeEditField_2.Value;
            if isequal(f1,0) && isequal(f2,0)
               focalLength = [3408.59,3408.87];
            else
               focalLength = [f1,f2];
            end
            if isequal(p1,0) && isequal(p2,0)
                principalPoint =[3117.24,2064.07];
            else
                principalPoint = [p1,p2];
            end
            if isequal(im1,0) && isequal(im2,0)
                imageSize = size(app.images{1});
            else
                imageSize = [im1,im2];
            end
            app.intrinsics = cameraIntrinsics(focalLength,principalPoint,imageSize);
            app.ReadyLamp.Color = "0.00,1.00,0.00";
            app.ConfirmButtonPressed = true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            choice = questdlg('\fontsize{16}Do you want to close?','Close','Close','Cancel',app.opts1);
            switch choice
                case 'Close'
                    delete(app.UIFigure);
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: ResetButton
        function ResetMenuSelected(app, event)
            choice = questdlg('\fontsize{16}Do you want to reset?','Reset','Reset','Cancel',app.opts1);
            switch choice
                case 'Reset'
                    app.ReadyLamp.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_2.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_3.Color = "1.00,0.00,0.00";
                    cla(app.UIAxes);
                    app.focalLengthEditField.Value = 0;
                    app.focalLengthEditField_2.Value = 0;
                    app.principalPointEditField.Value = 0;
                    app.principalPointEditField_2.Value = 0;
                    app.imageSizeEditField.Value = 0;
                    app.imageSizeEditField_2.Value = 0;
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox("\fontsize{16}This is a demo.","About this application",app.opts)
        end

        % Button pushed function: detectcameraParButton
        function detectcameraParButtonPushed(app, event)
            folder = uigetdir('select the folder');
            if isequal(folder,0)
                errordlg("No folder selected","error",app.opts);
                return;
            end
            fileID = fullfile(folder, 'cameras.txt');
            if exist(fileID,'file') == 0
                msgbox("\fontsize{16}The camera parameters were not found in the current folder, please enter them manually.","error",app.opts)
                return;
            else
                camPar = readtable(fileID);
                camPar = table2array(camPar(2,3:8));
                app.focalLengthEditField.Value = camPar(1,3);
                app.focalLengthEditField_2.Value =camPar(1,4);
                app.principalPointEditField.Value = camPar(1,5);
                app.principalPointEditField_2.Value = camPar(1,6);
                app.imageSizeEditField.Value = camPar(1,2);
                app.imageSizeEditField_2.Value = camPar(1,1);
                msgbox("\fontsize{16}Camera parameters are entered.","Hint",app.opts)
            end
            app.detectcameraParButtonPressed = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Pointcloud')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.focalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.focalLengthEditFieldLabel.Text = 'focalLength';
            app.focalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField.ValueDisplayFormat = '%.2f';
            app.principalPointEditFieldLabel = uilabel(app.UIFigure);
            app.principalPointEditFieldLabel.Text = 'principalPoint';
            app.principalPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField.ValueDisplayFormat = '%.2f';
            app.imageSizeEditFieldLabel = uilabel(app.UIFigure);
            app.imageSizeEditFieldLabel.Text = 'imageSize';
            app.imageSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField.ValueDisplayFormat = '%.2f';
            app.focalLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField_2.ValueDisplayFormat = '%.2f';
            app.principalPointEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField_2.ValueDisplayFormat = '%.2f';
            app.imageSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField_2.ValueDisplayFormat = '%.2f';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp_2Label = uilabel(app.UIFigure);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.ReadyLamp_2 = uilamp(app.UIFigure);
            app.ReadyLamp_3Label = uilabel(app.UIFigure);
            app.ReadyLamp_3Label.Text = 'Ready';
            app.ReadyLamp_3 = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetButton.Text = 'Reset';
            app.detectcameraParButton = uibutton(app.UIFigure, 'push');
            app.detectcameraParButton.ButtonPushedFcn = createCallbackFcn(app, @detectcameraParButtonPushed, true);
            app.detectcameraParButton.Text = 'detect cameraPar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_challenge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end